classdef SampSampGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        fileToolbar       matlab.ui.container.Menu
        saveFcn           matlab.ui.container.Menu
        loadFcn           matlab.ui.container.Menu
        exitFcn           matlab.ui.container.Menu
        openFile          matlab.ui.control.Button
        listbox           matlab.ui.control.ListBox
        TriggerPanel      matlab.ui.container.Panel
        stopTrigger       matlab.ui.container.ButtonGroup
        MintrigButton     matlab.ui.control.RadioButton
        MaxtrigButton     matlab.ui.control.RadioButton
        trigOrNot         matlab.ui.control.DropDown
        triggerUnit_2     matlab.ui.control.Label
        triggerUnit_1     matlab.ui.control.Label
        startTrigValue    matlab.ui.control.EditField
        startTrigger      matlab.ui.control.Label
        stopTrigValue     matlab.ui.control.EditField
        AnalogInput       matlab.ui.container.ButtonGroup
        Triggera0DatauserselectionLabel  matlab.ui.control.Label
        a0                matlab.ui.control.CheckBox
        a7                matlab.ui.control.CheckBox
        a6                matlab.ui.control.CheckBox
        a5                matlab.ui.control.CheckBox
        a4                matlab.ui.control.CheckBox
        a3                matlab.ui.control.CheckBox
        a2                matlab.ui.control.CheckBox
        a1                matlab.ui.control.CheckBox
        Save              matlab.ui.container.Panel
        name              matlab.ui.control.EditField
        openFolder        matlab.ui.control.Button
        saveDir           matlab.ui.control.Label
        startPanel        matlab.ui.container.Panel
        showTrigger       matlab.ui.control.CheckBox
        startDAQ          matlab.ui.control.Button
        stopDAQ           matlab.ui.control.Button
        freqText          matlab.ui.control.Label
        timeoutText       matlab.ui.control.Label
        updateText        matlab.ui.control.Label
        viewWindowText    matlab.ui.control.Label
        delayText         matlab.ui.control.Label
        sampleFrequency   matlab.ui.control.EditField
        timeoutSecs       matlab.ui.control.EditField
        updateFrequency   matlab.ui.control.EditField
        viewWindowLength  matlab.ui.control.EditField
        delaySamples      matlab.ui.control.EditField
        Device            matlab.ui.control.DropDown
        unit1             matlab.ui.control.Label
        unit2             matlab.ui.control.Label
        unit3             matlab.ui.control.Label
        unit4             matlab.ui.control.Label
        unit5             matlab.ui.control.Label
        triggerPlot       matlab.ui.control.UIAxes
        dataPlot          matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % Information about the connected DAQ device
        daqConnection = "Dev1"; % Refers to what NI device is being used
        daqType = "ni"; % Name of daqDevice
        DAQ % Handle to connected DAQ hardware object
        analogueInputChoice; % What is the chosen input source (represents a0 - a7)
        
        % Options relating to trigger values for DAQ
        useTrigger = true;
        startTrigVal
        stopTrigVal
        TrigActive
        maxCaptureTime = 15; % Determines the largest possible recording time for a single data block

        trigEnd % Index pointer to the end of a data block

        aboveUnder % Change to something else, confusing

        % State of the DAQ device during data acquisition
        currentState = 'Acquisition.ReadyForCapture';

        % Path and name to the .mat file representing the current recording
        recordingName;
        % Bounderies for the plotwindows
        axisXmin = 0; 
        axisXmax = 11;
        axisYmin = 0;
        axisYmax = 3;  

        countOnOff = 2;      % Counter that makes sure that start button is green when sampling end red when not(that it dosent flicker)        
        subPlot = '1:5:end'; % Plots every 5th sample

        % Define properties regarding saving data
        files                               % List of individual .mat files containing data
        fileNameData = 'data_block';        % Each data block gets a name data_block1,...,data_block20,....
        fileNameTime = 'ticktimes_block';   % To each block of data a corresponding time stamp is saved with the name ticktimes_block1,....tcktimes_block20,....
        dataBlock = 'data_block';           % Each data block gets a name data_block1,...,data_block20,....
        fileCounter = 1;                    % Name counter of data files ex. data_block1, data_block2        
        counter                             % Counts data blocks for long recording sessions
        lastSaveTime = 0;                   % Contains timestamp for last autosave during no trigger

        FIFOBuffer = [];
        captureStartMoment                  % Index pointing to beginning of data block
        captureEndMoment                    % Index to end of data block

        % Links to the folder created when saving data
        currentRecordingFolder
    end
    
    methods (Access = private)
        function scansAvailable_Callback(app, src, ~)
        % scansAvailable_Callback Executes on DAQ ScansAvailable event
        % This callback function gets executed periodically as more data is acquired by the daqDevice.
        % This callback is setup in the script 'configureDAQ.m' 
            tic
            if ~isvalid(app)
                return
            end
            
            % Continuous acquisition data and timestamps are stored in FIFO data buffers
            % Calculate required buffer size -- this should be large enough to accomodate the
            % the data required for the live view time window and the data for the requested
            % capture duration.
            
            [data,timestamps] = read(src, src.ScansAvailableFcnCount, 'OutputFormat','Matrix');
            
            bufferSize = str2double(app.timeoutSecs.Value) * str2double(app.sampleFrequency.Value);

            % FIFO Buffer setup:
            % (1) is timestamps
            % (2) is trigger data
            % (3) is participant data
            app.FIFOBuffer = storeDataInFIFO(app.FIFOBuffer, bufferSize, timestamps, data);
            % App state control logic 
            switch app.currentState
                case 'Acquisition.Buffering'
                    % Buffering pre-trigger data, this points to a script
                    % in the 'Functions' folder
                    if isEnoughDataBuffered(app.FIFOBuffer(:, 1), app.delaySamples.Value)
                        % Depending if user wants to employ a software
                        % trigger or not, change state
                        app.currentState = 'Capture.CapturingData';
                        if app.useTrigger
                            app.currentState = 'Capture.LookingForTrigger';
                        else
                            app.captureStartMoment = app.lastSaveTime;
                            app.currentState = 'Capture.CapturingData';
                        end
                    end
                case 'Capture.LookingForTrigger'
                    % Looking for trigger event in the latest data
                    trigActive = detectStartTrigger(app);
                    if trigActive
                        app.currentState = 'Capture.CapturingData';
                    end
                case 'Capture.CapturingData'
                    % Get index for where data block starts
                    dataBlockStartIndex = find(app.FIFOBuffer(:, 1) >= app.captureStartMoment, 1, 'first');
                    dataBlockLength = size(app.FIFOBuffer, 1) - dataBlockStartIndex;
                    % Only show available data, up to the maximum window, and only show data from the current data block
                    samplesToPlot = min([round(str2double(app.viewWindowLength.Value) * src.Rate), size(app.FIFOBuffer,1), dataBlockLength]);
                    firstPoint = size(app.FIFOBuffer, 1) - samplesToPlot + 1;

                    % Plot the trigger data if the user has selected the relevant checkbox
                    if app.showTrigger.Value
                        plot(app.triggerPlot, app.FIFOBuffer(firstPoint:end, 1), ...
                             app.FIFOBuffer(firstPoint:end, 2));
                    end
                    % Always plot the recorded data when collecting data 
                    plot(app.dataPlot, app.FIFOBuffer(firstPoint:end, 1), ...
                         app.FIFOBuffer(firstPoint:end, 3));

                    % Wrap axis limits around the data
                    app.triggerPlot.XLim = [app.FIFOBuffer(firstPoint, 1), app.FIFOBuffer(end, 1)];
                    app.dataPlot.XLim = [app.FIFOBuffer(firstPoint, 1), app.FIFOBuffer(end, 1)];
                    drawnow
                    % First case: when triggers are used, check to save
                    % data by either a timeout 
                    if app.trigOrNot == 1
                        timeoutResult = str2double((app.FIFOBuffer(end,1)-app.captureStartMoment)) > str2double(app.timeoutSecs.Value);
                        endTriggerResult = detectEndTrigger(app);
                        if any(timeoutResult) || any(endTriggerResult)
                            completeCapture(app)
                        end
                    % Second case: no trigger is used, save the current
                    % data as it is being recorded to prevent dropped data
                    else
                        timeoutResult = app.FIFOBuffer(end,1) - app.lastSaveTime > str2double(app.timeoutSecs.Value) - 1;
                        if any(timeoutResult)
                            completeCapture_noTrigg(app)
                        end
                    end
            end
            toc
        end

        function trigActive = detectStartTrigger(app)
        %detectTrigger Detects trigger condition and updates relevant app properties
        % Updates TrigActive, TrigMoment, and CaptureStartMoment app properties
            freq       = str2double(app.sampleFrequency.Value);
            updateFreq = str2double(app.updateFrequency.Value);
            scansAvailable = freq * (updateFreq / 1000);
            % Get index to check only new data for trigger
            index = size(app.FIFOBuffer, 1) - scansAvailable + 1;
            
            trigConfig.Channel = 1; % Represents photo-diode channel
            trigConfig.Level = app.startTrigValue.Value;
            trigConfig.Condition = 'Rising';
            [trigActive, app.captureStartMoment] = ...
                trigDetect(app.FIFOBuffer(index: end, 1), app.FIFOBuffer(index: end, 2), trigConfig);
        end

        function result = detectEndTrigger(app)
        %detectTrigger Detects trigger condition and updates relevant app properties
        % Updates TrigActive, TrigMoment, and CaptureStartMoment app properties
            freq            = str2double(app.sampleFrequency.Value);
            updateFreq      = str2double(app.updateFrequency.Value);
            scansAvailable = freq * (updateFreq / 1000);
            % Get index to check only new data for trigger
            index = size(app.FIFOBuffer, 1) - scansAvailable + 1;
            
            trigConfig.Channel = 1; % Represents Stabby-stab channel
            trigConfig.Level = app.stopTrigValue.Value;
            trigConfig.Condition = 'Falling';
            [result, app.captureEndMoment] = ...
                trigDetect(app.FIFOBuffer(index: end, 1), app.FIFOBuffer(index: end, 2), trigConfig);
        end

        function completeCapture(app)
        % completeCapture Saves captured data to user folder and resets DAQ
        % device to wait for another trigger

            % Find index of first sample in data buffer to be captured
            firstSampleIndex = find(app.FIFOBuffer(:, 1) >= app.captureStartMoment, 1, 'first');
            
            % Find index of last sample in data buffer that complete the capture
            lastSampleIndex = find(app.FIFOBuffer(:, 1) >= app.captureEndMoment, 1, 'first');

            if isempty(firstSampleIndex) || isempty(lastSampleIndex) || lastSampleIndex > size(app.FIFOBuffer(:, 1), 1)
                % If the index's either don't exist or go outside the
                % expected range of the captured data, abort.
                % Fill this with more sampsamp related warnings
                uialert(fig, 'Could not complete capture.', 'Capture error');
                return
            end

            % Set dynamic variable names based on current block
            dataBlockName = strcat("Data_Block_", num2str(app.counter));
            timeBlockName = strcat("Ticktime_Block_", num2str(app.counter));
            
            % Extract capture data and shift timestamps so that 0 corresponds to the trigger moment
            % Save these to Data_Block_n and Ticktime_Block_n, respectively 
            captureDataCmd = strcat(dataBlockName, "= [app.dataFIFOBuffer(firstSampleIndex:lastSampleIndex), " + ...
                "app.triggerPlotFIFOBuffer(firstSampleIndex:lastSampleIndex)];");
            ticktimeCmd = strcat(timeBlockName, "= convertTo(datetime('now'),'epochtime','Epoch','1970-01-01');");
            % Run these commands
            eval(captureDataCmd)
            eval(ticktimeCmd)
    
            % Create and run a save script using our dynamic variables
            saveCmd = strcat("save(app.recordingName, '", dataBlockName, ...
                "', '", timeBlockName, "', '-append');");
            eval(saveCmd)
    
            %---Save the handle structure-----
            app.counter = app.counter + 1;
            %---------------------------------
    
            %---start the daq-object again-----
            try
                % start(app.DAQ, 'Continuous')
                app.currentState = 'Capture.LookingForTrigger';
            catch
                disp 'Timed out';
            end
            %----------------------------------     
        end

        function completeCapture_noTrigg(app)
        % completeCapture Saves captured data to user folder for when no
        % trigger is used, ends recording session.
            
            % Set dynamic variable names based on current block
            dataBlockName = strcat("Data_Block_", num2str(app.counter));
            timeBlockName = strcat("Ticktime_Block_", num2str(app.counter));

            % Find the last time data was autosaved and grab its index
            saveConditionMet = app.FIFOBuffer(:, 1) >= app.lastSaveTime;
            saveIndex = 1 + find(saveConditionMet==1, 1, 'first'); %#ok<NASGU> This is fine as it is used in an eval() command
            app.lastSaveTime = app.FIFOBuffer(end, 1);
            
            % Extract capture data and shift timestamps so that 0 corresponds to the trigger moment
            % Save these to Data_Block_n and Ticktime_Block_n, respectively 
            captureDataCmd = strcat(dataBlockName, "= [app.FIFOBuffer(saveIndex:end, 3), " + ...
                "app.FIFOBuffer(saveIndex:end, 2)];");
            ticktimeCmd = strcat(timeBlockName, "= convertTo(datetime('now'),'epochtime','Epoch','1970-01-01');");
            % Run these commands
            eval(captureDataCmd)
            eval(ticktimeCmd)
    
            % Create and run a save script using our dynamic variables
            saveCmd = strcat("save(app.recordingName, '", dataBlockName, ...
                "', '", timeBlockName, "', '-append');");
            eval(saveCmd)
    
            %---Save the handle structure-----
            app.counter = app.counter + 1;
            %--------------------------------- 
        end

        function configureDAQ(app)
            % Obtain variables from app properties
            ai              = app.analogueInputChoice;
            d               = daq(app.daqType);
            freq            = str2double(app.sampleFrequency.Value);
            updateFreq      = str2double(app.updateFrequency.Value);
            
            % When you add channels you need the number of the
            % port to sample from to activate it, under this a vector is created
            % which elements represent 
            
            for i=0:7
                if ai(i+1) == 1
                    % If selected, add a channel to be recorded to DAQ object
                    addinput(d, app.daqConnection, i, "Voltage");
                end
            end
            
            %-------------------Specific properties for trigger------------------------
            
            % Configure DAQ ScansAvailableFcn callback function
            d.ScansAvailableFcn = @(src,event) scansAvailable_Callback(app, src, event);
            d.ScansAvailableFcnCount = freq * (updateFreq / 1000); % Update Freq is in milli seconds, put back into seconds
            
            % Sets sample rate of ai will be rounded off to closest possible sampling rate 
            %(sample rate is limite by stepsize 1/250000 and has to be modulus 0 with it..)
            d.Rate = freq;
            
            app.DAQ = d;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sampsamp_OpeningFcn(app, varargin)
            % --- Executes just before sampsamp is made visible.
            uiWindow=gcf;

            % Set access to figure window for easy editing
            setappdata(0,'samsamWindow',uiWindow);

            % Set the default path to SampSamp's main folder
            defaultPath = which("SampSampGUI");
            defaultPath = strsplit(defaultPath, 'SampSampGUI');

            app.saveDir.Text = defaultPath{1};

            app.analogueInputChoice = [app.a0.Value, app.a1.Value, ...
                                       app.a2.Value, app.a3.Value, ...
                                       app.a4.Value, app.a5.Value, ...
                                       app.a6.Value, app.a7.Value];     

            app.startTrigVal = str2double(app.startTrigValue.Value);
            app.stopTrigVal  = str2double(app.stopTrigValue.Value);
            app.useTrigger   = app.trigOrNot.Value;
            app.aboveUnder   = app.stopTrigger.SelectedObject;
        end

        % Menu selected function: exitFcn
        function exit_Callback(app, event)
            % --- Executes on button press in exit.
            close(gcf)
        end

        % Value changed function: listbox
        function listbox_Callback(app, event)
            % --- Executes on selection change in listbox.
            val = app.listbox.Value;

            if val > 1
                load(app.matFile,app.files(val-1).name)
            end

        end

        % Button pushed function: openFile
        function openFile_Callback(app, event)
            % Loads a user selected .mat file of previously saved data
            [file, folder]= uigetfile('');
            if isequal(file,0) || isequal(folder,0)
                disp('Operation canceled')
            else
                app.matFile = [folder file];
                app.files = whos ('-file', [folder file]);
                storlek = size(app.files);
                N = (storlek(1)-1)/2;
            
                app.listbox.Items = {'load new file'};
                for i = 1:N-3
                    oldString = app.listbox.Items;
                    app.listbox.Items = [oldString(:)' {app.files(i).name}];
                end
            end
        end

        % Button pushed function: openFolder
        function openFolder_Callback(app, event)
            % --- Executes on button press in openFolder.
            
            dir=uigetdir;
            if ~dir==0 % If not cancelled, save user choice
                app.saveDir.Text = dir;
            end
        end

        % Button pushed function: stopDAQ
        function stopDAQ_Callback(app, event)
            % --- Executes on button press in reset.
            
            if ~isempty(app.DAQ)
                stop(app.DAQ);
            end
            if app.trigOrNot.Value == 0
                completeCapture_noTrigg(app);
            end
            app.startDAQ.FontColor = 'red';
            app.currentState = 'Acquisition.ReadyForCapture';
        end

        % Button pushed function: startDAQ
        function startDAQ_Callback(app, event)
            % --- Executes on button press in startDAQ.
            daqreset;

            % Reset FIFO buffer data
            app.FIFOBuffer = [];

            % Reset Data and Timestamps
            app.lastSaveTime = 0;
            
            recordStart = string (datetime("now"));
            recordStart = strrep(recordStart, ":", "_");
            newFolder = append (string(app.saveDir.Text), "\", recordStart);
            app.currentRecordingFolder = newFolder;
            mkdir(newFolder);

            app.recordingName = append(newFolder, '\', recordStart, '.mat');
            
            info = '---------Data from sampsamp--------------';
            save(app.recordingName,'info');
            
            % Reset files variable
            app.files={''};

            % Function to load selected channels and apply daq settings to a new daq object 
            configureDAQ(app);
            
            % Set counter for datablock
            app.counter = 1;
            
            % Start DAQ device, quit if errors occcur
            try
                start(app.DAQ,'continuous');
                tic
            catch error
                disp('error starting DAQ device');
                disp(error)
                return
            end

            app.currentState = 'Acquisition.Buffering';
            app.startDAQ.FontColor = 'green';
            
            if app.useTrigger == 0
                % Need to find replacement in modern DAQ toolbox, there was
                % code here to be able to istantly 'getdata' and save the
                % data, time, and 'abstime'
            end
        end

        % Value changed function: trigOrNot
        function trigOrNot_Callback(app, event)
            % --- Executes on selection change in trigOrNot.

            val = app.trigOrNot.Value;
            app.useTrigger = val;
            switch val
                case 1
                    % Enable trigger options
                    app.startTrigger.Enable = 'On';
                    app.startTrigValue.Enable = 'On';
                    app.triggerUnit_1.Enable = 'On';
                    app.triggerUnit_2.Enable = 'On';
                    app.stopTrigger.Enable = 'On';
                    app.stopTrigValue.Enable = 'On';
                case 0
                    % Disable trigger options
                    app.startTrigger.Enable = 'Off';
                    app.startTrigValue.Enable = 'Off';
                    app.triggerUnit_1.Enable = 'Off';
                    app.triggerUnit_2.Enable = 'Off';
                    app.stopTrigger.Enable = 'Off';
                    app.stopTrigValue.Enable = 'Off';
            end
        end

        % Menu selected function: saveFcn
        function saveFcnMenuSelected(app, event)
            
        end

        % Menu selected function: loadFcn
        function loadFcnMenuSelected(app, event)
            
        end

        % Value changed function: timeoutSecs
        function timeoutSecsValueChanged(app, event)
            if str2double(app.timeoutSecs.Value) < 5
                msgbox("Timeout cannot be less than 5 seconds","Warning","warn");
                app.timeoutSecs.Value = '5';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sampsamp';
            app.figure1.Tag = 'figure1';
            app.fileToolbar = uimenu(app.figure1);
            app.fileToolbar.Text = 'File';
            app.fileToolbar.Tag = 'uimenu1';
            app.saveFcn = uimenu(app.fileToolbar);
            app.saveFcn.MenuSelectedFcn = createCallbackFcn(app, @saveFcnMenuSelected, true);
            app.saveFcn.Text = 'Save Config';
            app.loadFcn = uimenu(app.fileToolbar);
            app.loadFcn.MenuSelectedFcn = createCallbackFcn(app, @loadFcnMenuSelected, true);
            app.loadFcn.Text = 'Load Config';
            app.exitFcn = uimenu(app.fileToolbar);
            app.exitFcn.MenuSelectedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exitFcn.Text = 'Exit';
            app.dataPlot = uiaxes(app.figure1);
            app.dataPlot.Tag = 'dataPlot';
            app.triggerPlot = uiaxes(app.figure1);
            app.triggerPlot.Tag = 'triggerPlot';
            app.startPanel = uipanel(app.figure1);
            app.startPanel.Title = 'Sampling';
            app.startPanel.Tag = 'startPanel';
            app.unit5 = uilabel(app.startPanel);
            app.unit5.Tag = 'text12';
            app.unit5.Text = 'Samples';
            app.unit4 = uilabel(app.startPanel);
            app.unit4.Tag = 'text11';
            app.unit4.Text = '[s]';
            app.unit3 = uilabel(app.startPanel);
            app.unit3.Tag = 'text11';
            app.unit3.Text = '[ms]';
            app.unit2 = uilabel(app.startPanel);
            app.unit2.Tag = 'text11';
            app.unit2.Text = '[s]';
            app.unit1 = uilabel(app.startPanel);
            app.unit1.Tag = 'text10';
            app.unit1.Text = '[Hz]';
            app.Device = uidropdown(app.startPanel);
            app.Device.Items = {'Dev1', 'Dev2', 'Dev3'};
            app.Device.Tag = 'Device';
            app.Device.Value = 'Dev1';
            app.delaySamples = uieditfield(app.startPanel, 'text');
            app.delaySamples.Tag = 'delSam';
            app.delaySamples.Value = '25';
            app.viewWindowLength = uieditfield(app.startPanel, 'text');
            app.viewWindowLength.Tag = 'sec';
            app.viewWindowLength.Value = '10';
            app.updateFrequency = uieditfield(app.startPanel, 'text');
            app.updateFrequency.Tag = 'sec';
            app.updateFrequency.Value = '500';
            app.timeoutSecs = uieditfield(app.startPanel, 'text');
            app.timeoutSecs.ValueChangedFcn = createCallbackFcn(app, @timeoutSecsValueChanged, true);
            app.timeoutSecs.Tag = 'sec';
            app.timeoutSecs.Value = '1200';
            app.sampleFrequency = uieditfield(app.startPanel, 'text');
            app.sampleFrequency.Tag = 'freq';
            app.sampleFrequency.Value = '40000';
            app.delayText = uilabel(app.startPanel);
            app.delayText.Tag = 'text9';
            app.delayText.Text = 'Delay';
            app.viewWindowText = uilabel(app.startPanel);
            app.viewWindowText.Tag = 'TimeoutText';
            app.viewWindowText.Text = 'View Window';
            app.updateText = uilabel(app.startPanel);
            app.updateText.Tag = 'TimeoutText';
            app.updateText.Text = 'Update Every';
            app.timeoutText = uilabel(app.startPanel);
            app.timeoutText.Tag = 'TimeoutText';
            app.timeoutText.Text = 'Timeout';
            app.freqText = uilabel(app.startPanel);
            app.freqText.Tag = 'freqText';
            app.freqText.Text = 'f [Hz]';
            app.stopDAQ = uibutton(app.startPanel, 'push');
            app.stopDAQ.ButtonPushedFcn = createCallbackFcn(app, @stopDAQ_Callback, true);
            app.stopDAQ.Tag = 'reset';
            app.stopDAQ.Text = 'Stop-DAQ';
            app.startDAQ = uibutton(app.startPanel, 'push');
            app.startDAQ.ButtonPushedFcn = createCallbackFcn(app, @startDAQ_Callback, true);
            app.startDAQ.Tag = 'startDAQ';
            app.startDAQ.Text = 'Start-DAQ';
            app.showTrigger = uicheckbox(app.startPanel);
            app.showTrigger.Text = 'Show trigger';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Save Options';
            app.Save.Tag = 'Save';
            app.saveDir = uilabel(app.Save);
            app.saveDir.Tag = 'saveDir';
            app.saveDir.Text = 'C:\Users';
            app.openFolder = uibutton(app.Save, 'push');
            app.openFolder.ButtonPushedFcn = createCallbackFcn(app, @openFolder_Callback, true);
            app.openFolder.Tag = 'openFolder';
            app.openFolder.Text = 'Open folder';
            app.name = uieditfield(app.Save, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'TagnameOfFile';
            app.AnalogInput = uibuttongroup(app.figure1);
            app.AnalogInput.Title = 'Analog input';
            app.AnalogInput.Tag = 'AnalogInput';
            app.a1 = uicheckbox(app.AnalogInput);
            app.a1.Tag = 'a1';
            app.a1.Text = 'a1';
            app.a2 = uicheckbox(app.AnalogInput);
            app.a2.Tag = 'a2';
            app.a2.Text = 'a2';
            app.a3 = uicheckbox(app.AnalogInput);
            app.a3.Tag = 'a3';
            app.a3.Text = 'a3';
            app.a4 = uicheckbox(app.AnalogInput);
            app.a4.Tag = 'a4';
            app.a4.Text = 'a4';
            app.a5 = uicheckbox(app.AnalogInput);
            app.a5.Tag = 'a5';
            app.a5.Text = 'a5';
            app.a6 = uicheckbox(app.AnalogInput);
            app.a6.Tag = 'a6';
            app.a6.Text = 'a6';
            app.a7 = uicheckbox(app.AnalogInput);
            app.a7.Tag = 'a7';
            app.a7.Text = 'a7';
            app.a7.Value = true;
            app.a0 = uicheckbox(app.AnalogInput);
            app.a0.Tag = 'a0';
            app.a0.Enable = 'off';
            app.a0.Text = 'a0';
            app.a0.Value = true;
            app.Triggera0DatauserselectionLabel = uilabel(app.AnalogInput);
            app.Triggera0DatauserselectionLabel.Text = {'Trigger = a0'; 'Data = user selection'};
            app.TriggerPanel = uipanel(app.figure1);
            app.TriggerPanel.Title = 'Trigger setings';
            app.TriggerPanel.Tag = 'TriggerPanel';
            app.stopTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.stopTrigValue.Tag = 'stopTrig';
            app.stopTrigValue.Value = '3.5';
            app.startTrigger = uilabel(app.TriggerPanel);
            app.startTrigger.Tag = 'triggerValue';
            app.startTrigger.Text = 'Start when trig >';
            app.startTrigValue = uieditfield(app.TriggerPanel, 'text');
            app.startTrigValue.Tag = 'triggVal';
            app.startTrigValue.Value = '3';
            app.triggerUnit_1 = uilabel(app.TriggerPanel);
            app.triggerUnit_1.Tag = 'text13';
            app.triggerUnit_1.Text = '[V]';
            app.triggerUnit_2 = uilabel(app.TriggerPanel);
            app.triggerUnit_2.Tag = 'text14';
            app.triggerUnit_2.Text = '[V]';
            app.trigOrNot = uidropdown(app.TriggerPanel);
            app.trigOrNot.Items = {'With trigger', 'No trigger'};
            app.trigOrNot.ItemsData = [1 0];
            app.trigOrNot.ValueChangedFcn = createCallbackFcn(app, @trigOrNot_Callback, true);
            app.trigOrNot.Tag = 'trigOrNot';
            app.trigOrNot.Value = 1;
            app.stopTrigger = uibuttongroup(app.TriggerPanel);
            app.stopTrigger.Title = 'Stop when:';
            app.MaxtrigButton = uiradiobutton(app.stopTrigger);
            app.MaxtrigButton.Text = 'Max (trig)  <';
            app.MaxtrigButton.Value = true;
            app.MintrigButton = uiradiobutton(app.stopTrigger);
            app.MintrigButton.Text = 'Min  (trig)  <';
            app.listbox = uilistbox(app.figure1);
            app.listbox.Items = {'Listbox'};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.listbox.Tag = 'listbox';
            app.listbox.Value = 'Listbox';
            app.openFile = uibutton(app.figure1, 'push');
            app.openFile.ButtonPushedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Tag = 'openFile';
            app.openFile.Text = 'Open file';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SampSampGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sampsamp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end