classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uimenu1           matlab.ui.container.Menu
        uimenu1_1         matlab.ui.container.Menu
        uimenu1_2         matlab.ui.container.Menu
        uimenu1_3         matlab.ui.container.Menu
        uimenu1_4         matlab.ui.container.Menu
        uimenu1_5         matlab.ui.container.Menu
        uimenu1_6         matlab.ui.container.Menu
        uimenu1_7         matlab.ui.container.Menu
        uimenu1_8         matlab.ui.container.Menu
        uimenu1_9         matlab.ui.container.Menu
        uimenu1_10        matlab.ui.container.Menu
        uimenu1_11        matlab.ui.container.Menu
        uimenu1_12        matlab.ui.container.Menu
        uimenu1_13        matlab.ui.container.Menu
        uimenu1_14        matlab.ui.container.Menu
        uimenu1_15        matlab.ui.container.Menu
        uimenu1_16        matlab.ui.container.Menu
        uimenu1_17        matlab.ui.container.Menu
        uimenu1_18        matlab.ui.container.Menu
        uimenu1_19        matlab.ui.container.Menu
        uimenu1_20        matlab.ui.container.Menu
        uimenu1_21        matlab.ui.container.Menu
        uimenu1_22        matlab.ui.container.Menu
        uimenu1_23        matlab.ui.container.Menu
        uimenu1_24        matlab.ui.container.Menu
        uimenu1_25        matlab.ui.container.Menu
        uimenu1_26        matlab.ui.container.Menu
        uimenu1_27        matlab.ui.container.Menu
        uimenu1_28        matlab.ui.container.Menu
        uimenu1_29        matlab.ui.container.Menu
        uimenu1_30        matlab.ui.container.Menu
        uimenu1_31        matlab.ui.container.Menu
        uimenu1_32        matlab.ui.container.Menu
        uimenu1_33        matlab.ui.container.Menu
        uimenu1_34        matlab.ui.container.Menu
        uimenu1_35        matlab.ui.container.Menu
        uimenu1_36        matlab.ui.container.Menu
        uimenu1_37        matlab.ui.container.Menu
        uimenu1_38        matlab.ui.container.Menu
        uimenu1_39        matlab.ui.container.Menu
        uimenu1_40        matlab.ui.container.Menu
        uimenu1_41        matlab.ui.container.Menu
        uimenu1_42        matlab.ui.container.Menu
        uimenu1_43        matlab.ui.container.Menu
        uimenu1_44        matlab.ui.container.Menu
        uimenu1_45        matlab.ui.container.Menu
        uimenu1_46        matlab.ui.container.Menu
        uimenu1_47        matlab.ui.container.Menu
        uimenu1_48        matlab.ui.container.Menu
        uimenu1_49        matlab.ui.container.Menu
        uimenu1_50        matlab.ui.container.Menu
        uimenu1_51        matlab.ui.container.Menu
        uimenu1_52        matlab.ui.container.Menu
        uimenu1_53        matlab.ui.container.Menu
        uimenu1_54        matlab.ui.container.Menu
        uimenu1_55        matlab.ui.container.Menu
        uimenu1_56        matlab.ui.container.Menu
        uimenu1_57        matlab.ui.container.Menu
        uimenu1_58        matlab.ui.container.Menu
        uimenu1_59        matlab.ui.container.Menu
        uimenu1_60        matlab.ui.container.Menu
        uimenu1_61        matlab.ui.container.Menu
        uimenu1_62        matlab.ui.container.Menu
        uimenu1_63        matlab.ui.container.Menu
        uimenu1_64        matlab.ui.container.Menu
        uimenu1_65        matlab.ui.container.Menu
        uimenu1_66        matlab.ui.container.Menu
        uimenu1_67        matlab.ui.container.Menu
        uimenu1_68        matlab.ui.container.Menu
        uimenu1_69        matlab.ui.container.Menu
        uimenu1_70        matlab.ui.container.Menu
        uimenu1_71        matlab.ui.container.Menu
        uimenu1_72        matlab.ui.container.Menu
        uimenu1_73        matlab.ui.container.Menu
        uimenu1_74        matlab.ui.container.Menu
        uimenu1_75        matlab.ui.container.Menu
        uimenu1_76        matlab.ui.container.Menu
        uimenu1_77        matlab.ui.container.Menu
        uimenu1_78        matlab.ui.container.Menu
        uimenu1_79        matlab.ui.container.Menu
        uimenu1_80        matlab.ui.container.Menu
        uimenu1_81        matlab.ui.container.Menu
        uitoolbar1        matlab.ui.container.Toolbar
        uitoggletool1     matlab.ui.container.toolbar.ToggleTool
        uitoggletool2     matlab.ui.container.toolbar.ToggleTool
        uitoggletool3     matlab.ui.container.toolbar.ToggleTool
        positionSampSamp  matlab.ui.control.Button
        openFile          matlab.ui.control.Button
        listbox           matlab.ui.control.ListBox
        exit              matlab.ui.control.Button
        TriggerPanel      matlab.ui.container.Panel
        trigOrNot         matlab.ui.control.DropDown
        text14            matlab.ui.control.Label
        text13            matlab.ui.control.Label
        triggVal          matlab.ui.control.EditField
        triggerValue      matlab.ui.control.Label
        stopTrig          matlab.ui.control.EditField
        stopValue         matlab.ui.control.Label
        AnalogInput       matlab.ui.container.ButtonGroup
        a0                matlab.ui.control.CheckBox
        a7                matlab.ui.control.CheckBox
        a6                matlab.ui.control.CheckBox
        a5                matlab.ui.control.CheckBox
        a4                matlab.ui.control.CheckBox
        a3                matlab.ui.control.CheckBox
        a2                matlab.ui.control.CheckBox
        a1                matlab.ui.control.CheckBox
        Save              matlab.ui.container.Panel
        name              matlab.ui.control.EditField
        openFolder        matlab.ui.control.Button
        saveDir           matlab.ui.control.Label
        uipanel6          matlab.ui.container.Panel
        home              matlab.ui.control.Button
        startPanel        matlab.ui.container.Panel
        Device            matlab.ui.control.DropDown
        text12            matlab.ui.control.Label
        text11            matlab.ui.control.Label
        text10            matlab.ui.control.Label
        delSam            matlab.ui.control.EditField
        text9             matlab.ui.control.Label
        reset             matlab.ui.control.Button
        sec               matlab.ui.control.EditField
        TimeoutText       matlab.ui.control.Label
        freqText          matlab.ui.control.Label
        freq              matlab.ui.control.EditField
        startDAQ          matlab.ui.control.Button
        triggerPlot       matlab.ui.control.UIAxes
        allDataPlot       matlab.ui.control.UIAxes
        dataPlot          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function basicDaqRealTimePlotCallback(app, hObject, eventdata, handles, samHandles)
            if hObject.samplesAvailable>0
            
            
                %this callback for no trigger
            
                size = hObject.SamplesAvailable;
                data = peekdata(hObject,size);
                cla(samHandles.plotFigDataAxis);
                plot(samHandles.plotFigDataAxis,data(:,1))
                cla (samHandles.plotFigTrig);
                plot(samHandles.plotFigTrig,data(1:10:end,2));
            
            end
        end
        
        function daqRealTimePlotCallback(app, hObject, eventdata, handles, samHandles)
            % hObject    handle to daqrealtimeplot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % function daqrealtimeplot(hObject,eventdata)
            size = hObject.SamplesAvailable;
            if hObject.SamplesAvailable > 160
            
            
                lastSamps=peekdata(hObject, samHandles.stopSize);
                data=peekdata(hObject,size);
            
                if samHandles.aboveUnder
                    test = max(lastSamps);
                    test = test(1);
                else
                    test = min(lastSamps);
                    test = test(1);
                    disp hejyo
                end
                if test<samHandles.stopTrig
                    stop(hObject)
                    counter = getappdata(0,'counter');
                    samps = hObject.SamplesAvailable;
                    [data,time,abstime]=getdata(hObject,samps);
            %        data = [data; abstime'];
            
                    samHandles.fileNameData = [samHandles.dataBlock num2str(counter)];
                    %%%value to ticktimes
            
                    ticktimes_block = etime(abstime,[1970 01 01 0 0 0 ]);
            %        samHandles.fileNameMat =[ '' num2str(abstime(1)) '' num2str(abstime(2)) '' num2str(abstime(3)) 'T' num2str(abstime(4)) '' num2str(abstime(5)) '' num2str(abstime(6))];
                    %folder = getappdata(0,'folder');
            
                    fileNameData = [samHandles.dataBlock num2str(counter)];
                    fileNameTime = [samHandles.fileNameTime num2str(counter)];
            
                    eval([sprintf(fileNameData) '=data'';']);
                    eval([sprintf(fileNameTime) '=ticktimes_block;']);
            
                    save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                        ,fileNameData,fileNameTime,'-append');
            
            %         try
            %                save([ 'Z:\' samHandles.fileNameMat '.mat']...
            %                ,fileNameData,fileNameTime,'-append');
            %             catch
            %                 disp 'did not manage to connect to network drive Z: make sure you are logged in on network drive!';
            %             end
            
                    %---Save the handle structure-----
                    counter = counter + 1;
                    setappdata(0,'counter',counter);
                    setappdata(0,'samHandles',samHandles);
                    %---------------------------------
            
                    %---start the daq-object again-----
                    try
            
                        start(hObject)
                    catch
                        disp 'Timed out';
                    end
                    %----------------------------------
                end
            %     figure(samHandles.plotFigTrig);


                N=length(data(:,1));
                T=N/samHandles.freq;
                x=linspace(0,T,N);
            
                if T<11
                    cla(samHandles.plotFigTrigAxis)
                    axis(samHandles.plotFigTrigAxis,[0 11 0 4])
                    hold (samHandles.plotFigTrigAxis,'on')
            
                    plot(samHandles.plotFigTrigAxis,x,data(sprintf(samHandles.subPlot),1));
                    hold (samHandles.plotFigTrigAxis,'off')
                else
                    x2=linspace(T-10,T,10*samHandles.freq+1);
                    plot(samHandles.plotFigTrigAxis,x2,data(end-samHandles.freq*10:end,1));
                end
            
                try
            
                    if T<11
                        cla(samHandles.plotFigDataAxis);
                        axis(samHandles.plotFigDataAxis,[0 11 0 0.2])
            %             axis (samHandles.plotFigDataAxis,manual)
                        hold (samHandles.plotFigDataAxis,'on')
                        axis (samHandles.plotFigDataAxis,'auto y');
                        plot(samHandles.plotFigDataAxis,x,data(sprintf(samHandles.subPlot),2));
            
                        hold (samHandles.plotFigDataAxis,'off')
            
                    else
                        plot(samHandles.plotFigDataAxis,x2,data(end-samHandles.freq*10:end,2));
                    end
            
            
                end
            
            end
        end
        
        function drawLine_fcn(app, handles)
            
            try
                handles.lineXData = get(handles.line1,'XData');
            end
            
            handles.line1 = line('XData',handles.lineXData);
        end
        
        function edit8_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function freqTest_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to freqTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function missedData(app, hObject, eventdata, handles)
            % hObject    handle to edit8 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit8 as text
            %        str2double(get(hObject,'String')) returns contents of edit8 as a double
            disp oj
        end
        
        function secTest_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to secTest (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function settings_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in settings.
            
            % hObject    handle to settings (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            run sampsampTrouble
        end
        
        function timerCallback(app, hObject, eventdata, handles)
            % varargout  cell array for returning output args (see VARARGOUT);
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Get default command line output from handles structure
            % disp(rand)
            if ~isempty(daqfind)
                ai = daqfind;
                samHandles = getappdata(0,'samHandles');
                if strcmp(ai.Running, 'On') && samHandles.countOnOff>1
                    disp start
                    set(handles.startDAQ,'ForegroundColor', [0 1 0]);
                    samHandles.countOnOff = 0;
                end
                if strcmp(ai.Running, 'Off')&& samHandles.countOnOff<2
                    samHandles.countOnOff = samHandles.countOnOff + 1;
                    if samHandles.countOnOff>1
                       [data,time,abstime]=getdata(ai, ai.SamplesAvailable);
                    samHandles.fileNameData = [samHandles.dataBlock];
                    ticktimes_block = etime(abstime,[1970 01 01 0 0 0 ]);
            %        samHandles.fileNameMat =[ '' num2str(abstime(1)) '' num2str(abstime(2)) '' num2str(abstime(3)) 'T' num2str(abstime(4)) '' num2str(abstime(5)) '' num2str(abstime(6))];
                    %folder = getappdata(0,'folder');
            
                    fileNameData = [samHandles.dataBlock];
                    fileNameTime = [samHandles.fileNameTime];
            
                    eval([sprintf(fileNameData) '=data'';']);
                    eval([sprintf(fileNameTime) '=ticktimes_block;']);
            
                    save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat']...
                        ,fileNameData,fileNameTime,'-append');


                        disp stop
                        set(handles.startDAQ,'ForegroundColor',[1 0 0]);
            %             set(handles.startDAQ,'ForegroundColor',[1 0 0]);
                     end
                end
            
            setappdata(0,'samHandles',samHandles);
            end
        end
        
        function val1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to val1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function val2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to val2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: popupmenu controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor','white');
            end
        end
        
        function resetInteractions(app, event)
            % This function resets the states of the toggle tools that
            % impact user interactions.  It also resets the figure interactions.
             
            % Find all tools to reset.  Exclude the tool associated
            % with the event.
            interactiveTools = [app.uitoggletool1, app.uitoggletool2, app.uitoggletool3];
            interactiveTools(event.Source == interactiveTools) = [];
             
            % Set the state of the tools to 'off'.
            [interactiveTools.State] = deal('off');
             
            % Set figure interactions to 'off'.
            datacursormode(app.figure1, 'off')
            rotate3d(app.figure1, 'off');
            pan(app.figure1, 'off');
            zoom(app.figure1,'off');
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uimenu1_1.MenuSelectedFcn = 'undofit';
            app.uimenu1_2.MenuSelectedFcn = 'rmfit';
            app.uimenu1_4.MenuSelectedFcn = 'lastfit=showfit(''cste'');';
            app.uimenu1_6.MenuSelectedFcn = 'lastfit=showfit(''linear'');';
            app.uimenu1_7.MenuSelectedFcn = 'lastfit=showfit(''affine'');';
            app.uimenu1_8.MenuSelectedFcn = 'lastfit=showfit(''affineshift'');';
            app.uimenu1_10.MenuSelectedFcn = 'lastfit=showfit(''poly1'');';
            app.uimenu1_11.MenuSelectedFcn = 'lastfit=showfit(''poly2'');';
            app.uimenu1_12.MenuSelectedFcn = 'lastfit=showfit(''poly3'');';
            app.uimenu1_13.MenuSelectedFcn = 'lastfit=showfit(''poly4'');';
            app.uimenu1_14.MenuSelectedFcn = 'lastfit=showfit(''poly5'');';
            app.uimenu1_15.MenuSelectedFcn = 'lastfit=showfit(''poly6'');';
            app.uimenu1_16.MenuSelectedFcn = 'lastfit=showfit(''poly'');';
            app.uimenu1_18.MenuSelectedFcn = 'lastfit=showfit(''exp'');';
            app.uimenu1_19.MenuSelectedFcn = 'lastfit=showfit(''expc'');';
            app.uimenu1_20.MenuSelectedFcn = 'lastfit=showfit(''expdiv'');';
            app.uimenu1_21.MenuSelectedFcn = 'lastfit=showfit(''explim'');';
            app.uimenu1_23.MenuSelectedFcn = 'lastfit=showfit(''log'');';
            app.uimenu1_24.MenuSelectedFcn = 'lastfit=showfit(''logc'');';
            app.uimenu1_26.MenuSelectedFcn = 'lastfit=showfit(''power'');';
            app.uimenu1_27.MenuSelectedFcn = 'lastfit=showfit(''powerc'');';
            app.uimenu1_28.MenuSelectedFcn = 'lastfit=showfit(''powershift'');';
            app.uimenu1_30.MenuSelectedFcn = 'lastfit=showfit(''cngauss'');';
            app.uimenu1_31.MenuSelectedFcn = 'lastfit=showfit(''cfgauss'');';
            app.uimenu1_32.MenuSelectedFcn = 'lastfit=showfit(''ngauss'');';
            app.uimenu1_33.MenuSelectedFcn = 'lastfit=showfit(''fgauss'');';
            app.uimenu1_35.MenuSelectedFcn = 'lastfit=showfit(''sin'');';
            app.uimenu1_36.MenuSelectedFcn = 'lastfit=showfit(''cos'');';
            app.uimenu1_37.MenuSelectedFcn = 'lastfit=showfit(''sinc'');';
            app.uimenu1_38.MenuSelectedFcn = 'lastfit=showfit(''cosc'');';
            app.uimenu1_39.MenuSelectedFcn = 'lastfit=showfit(''sinphi'');';
            app.uimenu1_40.MenuSelectedFcn = 'lastfit=showfit(''sinphic'');';
            app.uimenu1_41.MenuSelectedFcn = 'showfit(''nearest'');';
            app.uimenu1_42.MenuSelectedFcn = 'showfit(''spline'');';
            app.uimenu1_43.MenuSelectedFcn = 'showfit(''cubic'');';
            app.uimenu1_44.MenuSelectedFcn = 'lastfit=showfit(''c+(x/x_0)^n'');';
            app.uimenu1_45.MenuSelectedFcn = 'lastfit=showfit(''E(k)=C*k^(-n)*exp(-k/k_c); log; C=5; n=2; k_c=100'');';
            app.uimenu1_46.MenuSelectedFcn = 'lastfit=showfit(''a*x+b*x^2 ; a=1 ; b=0.1'');';
            app.uimenu1_47.MenuSelectedFcn = 'showfit;';
            app.uimenu1_49.MenuSelectedFcn = 'editfit(1)';
            app.uimenu1_50.MenuSelectedFcn = 'editfit(2)';
            app.uimenu1_51.MenuSelectedFcn = 'editfit(3)';
            app.uimenu1_52.MenuSelectedFcn = 'editfit';
            app.uimenu1_53.MenuSelectedFcn = 'if strcmp(questdlg(''Are you sure you want to reset the user defined fits?'',''Reset User Fits'',''No''),''Yes''), delete(''userfit.mat''); efmenu; end';
            app.uimenu1_54.MenuSelectedFcn = 'efmenu';
            app.uimenu1_55.MenuSelectedFcn = 'editcoeff';
            app.uimenu1_56.MenuSelectedFcn = 'showresidual';
            app.uimenu1_57.MenuSelectedFcn = 'getslope(''equation'',''dialog'')';
            app.uimenu1_58.MenuSelectedFcn = 'showslope([],''dialog'')';
            app.uimenu1_60.MenuSelectedFcn = 'plotsample linear';
            app.uimenu1_61.MenuSelectedFcn = 'plotsample cste';
            app.uimenu1_62.MenuSelectedFcn = 'plotsample poly2';
            app.uimenu1_63.MenuSelectedFcn = 'plotsample osc';
            app.uimenu1_64.MenuSelectedFcn = 'plotsample damposc';
            app.uimenu1_65.MenuSelectedFcn = 'plotsample exp';
            app.uimenu1_66.MenuSelectedFcn = 'plotsample hist';
            app.uimenu1_67.MenuSelectedFcn = 'plotsample hist2';
            app.uimenu1_68.MenuSelectedFcn = 'plotsample power';
            app.uimenu1_69.MenuSelectedFcn = 'plotsample powco';
            app.uimenu1_71.MenuSelectedFcn = 'swx';
            app.uimenu1_72.MenuSelectedFcn = 'swy';
            app.uimenu1_73.MenuSelectedFcn = 'sw';
            app.uimenu1_74.MenuSelectedFcn = 'axis0';
            app.uimenu1_75.MenuSelectedFcn = 'axisc';
            app.uimenu1_76.MenuSelectedFcn = 'axisl';
            app.uimenu1_77.MenuSelectedFcn = 'edit fitparam';
            app.uimenu1_78.MenuSelectedFcn = 'docezyfit';
            app.uimenu1_79.MenuSelectedFcn = 'checkupdate_ef(''dialog'')';
            app.uimenu1_80.MenuSelectedFcn = 'web http://www.fast.u-psud.fr/ezyfit -browser';
            app.uimenu1_81.MenuSelectedFcn = 'about_ef(''dialog'')';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sampsamp_OpeningFcn(app, varargin)
            % --- Executes just before sampsamp is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to sampsamp (see VARARGIN)
            
            % Choose default command line output for sampsamp
            handles.output = hObject;
            
            % UIWAIT makes sampsamp wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            samHandles = samHandlesLoade();
            
            
            if ~exist('samHandles.nameNull')
                samHandles.nameNull = get(handles.name,'String');
            end
            
            h=gcf;
            setappdata(0,'samsam',h);
            setappdata(0,'t',0);
            setappdata(0,'samHandles',samHandles);
            
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end
            t = timer('TimerFcn', @(h, ev)posSampSamp(handles,2),'StartDelay',0.1);
            
            % set(t,'timerFcn',@app.timerCallback);
            start(t)
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: exit
        function exit_Callback(app, event)
            % --- Executes on button press in exit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to exit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close
        end

        % Button pushed function: home
        function home_Callback(app, event)
            % --- Executes on button press in home.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to home (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            home;
        end

        % Value changed function: listbox
        function listbox_Callback(app, event)
            % --- Executes on selection change in listbox.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox
            string = get(handles.listbox,'String');
            val = get(handles.listbox,'Value');
            samHandles = getappdata(0,'samHandles');
            if val > 1
                load(handles.matFile,handles.files(val-1).name)
            
                plot(handles.allDataPlot,eval([handles.files(val-1).name '(1,:)']))
                %[handles]=drawLine_fcn(handles);
            end
            guidata(hObject,handles)
        end

        % Button pushed function: openFile
        function openFile_Callback(app, event)
            % --- Executes on button press in openFile.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openFile (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [file folder]= uigetfile('C:\-(sampsamp)-\q.mat');
            [file folder]= uigetfile('E:\Yuri\q.mat');
            if isequal(file,0) || isequal(folder,0)
                disp('Operation canceled')
            else
            
                handles.matFile = [folder file];
                handles.files = whos ('-file', [folder file]);% [folder file]);
                storlek = size(handles.files);
                N = (storlek(1)-1)/2;
            
                set(handles.listbox,'String',{'load new file'});
                for i = 1:N-3
                    oldString = get(handles.listbox,'String');
                    set(handles.listbox,'String', {oldString{:} handles.files(i).name})
                end
            end
            guidata(hObject,handles)
        end

        % Button pushed function: openFolder
        function openFolder_Callback(app, event)
            % --- Executes on button press in openFolder.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to openFolder (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            dir=uigetdir;
            if ~dir==0
                set(handles.saveDir,'String',dir);
            end
        end

        % Button pushed function: positionSampSamp
        function positionSampSamp_Callback(app, event)
            % --- Executes on button press in positionSampSamp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to positionSampSamp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            [handles] = posSampSamp(handles,2);
        end

        % Button pushed function: reset
        function reset_Callback(app, event)
            % --- Executes on button press in reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ~isempty(daqfind)
                stop (daqfind);
            end
        end

        % Button pushed function: startDAQ
        function startDAQ_Callback(app, event)
            % --- Executes on button press in startDAQ.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to startDAQ (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            daqreset;
            
            % setappdata(0,'stophand',hnadles
            samHandles=getappdata(0,'samHandles');
            
            %----------samHandlesRefresh updates the samHandles to user choices-------
            %##############own funktion samHandlesRefresh###############
            [handles,samHandles] = sampHandelsRefresh(handles,samHandles);
            %-------------------------------------------------------------------------
            
            %------------------Positioning of sampsamp-------------------------
            %##################own function posSampSamp###################
            %[handles] = posSampSamp(handles,2);
            %------------------------------------------------------------------------
            
            
            mkdir(samHandles.folderDir);
            
            info = '---------Data from sampsamp--------------';
            save([samHandles.folderDir '\' samHandles.fileNameMat '.mat'],'info');
            
            
            % ------------------------------------------------------
            scnsize = get(0,'screensize');
            
            samsam = getappdata(0,'samsam');
            posSamsam = get(samsam,'Position');
            outpossamsam = get(samsam,'OuterPosition');
            bordersamsam = outpossamsam - posSamsam;
            samsamSize = outpossamsam([3 4]);
            
            sizeAvFoFig = [(scnsize(3) - samsamSize(1)) (scnsize(4)*0.9)];
            
            % global hfig.fig1
            % samHandles.plotFigData=handles.dataPlot; %  pos outpos borders apsize newPos
            % samHandles.plotFigDataAxis = handles.dataPlot;
            % samHandles.plotFigTrig = handles.triggerPlot;
            % samHandles.plotFigTrigAxis = handles.triggerPlot;
            samHandles.plotFigData=handles.triggerPlot; %  pos outpos borders apsize newPos
            samHandles.plotFigDataAxis = handles.triggerPlot;
            samHandles.plotFigTrig = handles.dataPlot;
            samHandles.plotFigTrigAxis = handles.dataPlot;
            cla(samHandles.plotFigDataAxis);
            cla(samHandles.plotFigTrigAxis);
            files={''};
            setappdata(0,'Files',files);
            % %----------------------------Creates the ai----------------------------
            
            
            ai = analoginput(samHandles.deviceMan,samHandles.device);
            if get(handles.trigg,'Value')==1
                [handles,samHandles, ai]=aiLoader(handles,samHandles,ai);
            else
                [handles,samHandles,ai] = aiLoaderNoTrigg(handles, samHandles,ai);
            end
            %------------------------SET PROPERTIES OF AI------------------------------
            set(handles.freq,'String',get(ai,'SampleRate'))
            setappdata(0,'samHandles',samHandles);
            
            %-------------Timer funktion for running collor-----------
            
            if ~isempty(timerfind)
                stop(timerfind)
                delete(timerfind)
            end


            t = timer('TimerFcn', @(h, ev) timerCallback(app, h, ev, handles));
            samHandles
            t.executionMode = 'fixedRate';
            % set(t,'timerFcn',@app.timerCallback);
            t.Period = 0.25;
            %---------------------------------------------------------
            if get(handles.trigOrNot,'Value')==1 && get(handles.trigg,'Value')==1
                set(ai,'TimerFcn',{@app.daqRealTimePlotCallback,handles,samHandles})%or some reason this doesent work when in aiLoader
            else
                set(ai,'TimerFcn',{@app.basicDaqRealTimePlotCallback,handles,samHandles})
            end
            
            
            counter = 1;
            setappdata(0,'counter',counter);
            try
                start(ai);
                start(t);
                tic
            catch
                disp 'error';
            end


            if   get(handles.trigg,'Value')==0
                [data,time,abstime]=getdata(ai);
                stri = datestr(now,'HHMMSS');
                stri = ['C:\Documents and Settings\sampDev\Desktop\SAMPSAMP\' stri '.mat'];
                save([ samHandles.folderDir '\' samHandles.fileNameMat '.mat'], 'data','time','abstime', '-append')
            
            end
            
            home
        end

        % Value changed function: trigOrNot
        function trigOrNot_Callback(app, event)
            % --- Executes on selection change in trigOrNot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to trigOrNot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns trigOrNot contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from trigOrNot
            val = get(handles.trigOrNot,'Value');
            switch val
                case 1
                    set(handles.triggerValue,'Visible','On')
                    set(handles.triggVal,'Visible','On')
                    set(handles.text13,'Visible','On')
                    set(handles.stopValue,'Visible','on')
                    set(handles.fromAbove,'Visible','On')
                    set(handles.fromUnder,'Visible','On')
                    set(handles.stopTrig,'Visible','On')
                    set(handles.text14,'Visible','On');
                case 2
                    set(handles.triggerValue,'Visible','Off')
                    set(handles.triggVal,'Visible','Off')
                    set(handles.text13,'Visible','off')
                    set(handles.stopValue,'Visible','off')
                    set(handles.fromAbove,'Visible','Off')
                    set(handles.fromUnder,'Visible','Off')
                    set(handles.stopTrig,'Visible','Off')
                    set(handles.text14,'Visible','Off');
            end
        end

        % Callback function: uitoggletool3
        function uitoggletool3_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable data tips based on the
            % tool's current state.
            state = app.uitoggletool3.State;
            datacursormode(app.figure1, char(state));
        end

        % Callback function: uitoggletool2
        function uitoggletool2_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable zoom-in based on the
            % tool's current state.
            state = app.uitoggletool2.State;
            zoomModeObject = zoom(app.figure1);
            if state
                zoomModeObject.Direction = 'in';
                zoomModeObject.Enable = 'on';
            else
                zoomModeObject.Enable = 'off';
            end
        end

        % Callback function: uitoggletool1
        function uitoggletool1_ClickedCallback(app, event)
            % Reset the states of interactive tools and reset all figure
            % interactions.
            app.resetInteractions(event);
             
            % Enable or disable pan based on the
            % tool's current state.
            state = app.uitoggletool1.State;
            pan(app.figure1, char(state));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'sampsamp';
            app.figure1.Tag = 'figure1';
            app.uimenu1 = uimenu(app.figure1);
            app.uimenu1.Text = 'EzyFit';
            app.uimenu1.Tag = 'uimenu1';
            app.uimenu1_1 = uimenu(app.uimenu1);
            app.uimenu1_1.Text = 'Undo fit';
            app.uimenu1_1.Tag = 'uimenu1_1';
            app.uimenu1_2 = uimenu(app.uimenu1);
            app.uimenu1_2.Text = 'Remove all fits';
            app.uimenu1_2.Tag = 'uimenu1_2';
            app.uimenu1_3 = uimenu(app.uimenu1);
            app.uimenu1_3.Separator = 'on';
            app.uimenu1_3.Text = 'Show Fit';
            app.uimenu1_3.Tag = 'uimenu1_3';
            app.uimenu1_4 = uimenu(app.uimenu1_3);
            app.uimenu1_4.Text = 'Constant';
            app.uimenu1_4.Tag = 'uimenu1_4';
            app.uimenu1_5 = uimenu(app.uimenu1_3);
            app.uimenu1_5.Text = 'Linear';
            app.uimenu1_5.Tag = 'uimenu1_5';
            app.uimenu1_6 = uimenu(app.uimenu1_5);
            app.uimenu1_6.Text = 'a*x';
            app.uimenu1_6.Tag = 'uimenu1_6';
            app.uimenu1_7 = uimenu(app.uimenu1_5);
            app.uimenu1_7.Text = 'a*x+b';
            app.uimenu1_7.Tag = 'uimenu1_7';
            app.uimenu1_8 = uimenu(app.uimenu1_5);
            app.uimenu1_8.Text = 'a*(x-b)';
            app.uimenu1_8.Tag = 'uimenu1_8';
            app.uimenu1_9 = uimenu(app.uimenu1_3);
            app.uimenu1_9.Text = 'Polynomial';
            app.uimenu1_9.Tag = 'uimenu1_9';
            app.uimenu1_10 = uimenu(app.uimenu1_9);
            app.uimenu1_10.Text = 'Order 1';
            app.uimenu1_10.Tag = 'uimenu1_10';
            app.uimenu1_11 = uimenu(app.uimenu1_9);
            app.uimenu1_11.Text = 'Order 2';
            app.uimenu1_11.Tag = 'uimenu1_11';
            app.uimenu1_12 = uimenu(app.uimenu1_9);
            app.uimenu1_12.Text = 'Order 3';
            app.uimenu1_12.Tag = 'uimenu1_12';
            app.uimenu1_13 = uimenu(app.uimenu1_9);
            app.uimenu1_13.Text = 'Order 4';
            app.uimenu1_13.Tag = 'uimenu1_13';
            app.uimenu1_14 = uimenu(app.uimenu1_9);
            app.uimenu1_14.Text = 'Order 5';
            app.uimenu1_14.Tag = 'uimenu1_14';
            app.uimenu1_15 = uimenu(app.uimenu1_9);
            app.uimenu1_15.Text = 'Order 6';
            app.uimenu1_15.Tag = 'uimenu1_15';
            app.uimenu1_16 = uimenu(app.uimenu1_9);
            app.uimenu1_16.Separator = 'on';
            app.uimenu1_16.Text = 'Other...';
            app.uimenu1_16.Tag = 'uimenu1_16';
            app.uimenu1_17 = uimenu(app.uimenu1_3);
            app.uimenu1_17.Text = 'Exponential';
            app.uimenu1_17.Tag = 'uimenu1_17';
            app.uimenu1_18 = uimenu(app.uimenu1_17);
            app.uimenu1_18.Text = 'a*exp(b*x)';
            app.uimenu1_18.Tag = 'uimenu1_18';
            app.uimenu1_19 = uimenu(app.uimenu1_17);
            app.uimenu1_19.Text = 'a*exp(b*x)+c';
            app.uimenu1_19.Tag = 'uimenu1_19';
            app.uimenu1_20 = uimenu(app.uimenu1_17);
            app.uimenu1_20.Text = 'a*exp(x/b)';
            app.uimenu1_20.Tag = 'uimenu1_20';
            app.uimenu1_21 = uimenu(app.uimenu1_17);
            app.uimenu1_21.Text = 'a*(1-exp(-x/b))';
            app.uimenu1_21.Tag = 'uimenu1_21';
            app.uimenu1_22 = uimenu(app.uimenu1_3);
            app.uimenu1_22.Text = 'Logarithmic';
            app.uimenu1_22.Tag = 'uimenu1_22';
            app.uimenu1_23 = uimenu(app.uimenu1_22);
            app.uimenu1_23.Text = 'a*log(b*x)';
            app.uimenu1_23.Tag = 'uimenu1_23';
            app.uimenu1_24 = uimenu(app.uimenu1_22);
            app.uimenu1_24.Text = 'a*log(x)+b';
            app.uimenu1_24.Tag = 'uimenu1_24';
            app.uimenu1_25 = uimenu(app.uimenu1_3);
            app.uimenu1_25.Text = 'Power';
            app.uimenu1_25.Tag = 'uimenu1_25';
            app.uimenu1_26 = uimenu(app.uimenu1_25);
            app.uimenu1_26.Text = 'a*x^n';
            app.uimenu1_26.Tag = 'uimenu1_26';
            app.uimenu1_27 = uimenu(app.uimenu1_25);
            app.uimenu1_27.Text = 'a*x^n+c';
            app.uimenu1_27.Tag = 'uimenu1_27';
            app.uimenu1_28 = uimenu(app.uimenu1_25);
            app.uimenu1_28.Text = 'a*(x+b)^n';
            app.uimenu1_28.Tag = 'uimenu1_28';
            app.uimenu1_29 = uimenu(app.uimenu1_3);
            app.uimenu1_29.Text = 'Gaussian';
            app.uimenu1_29.Tag = 'uimenu1_29';
            app.uimenu1_30 = uimenu(app.uimenu1_29);
            app.uimenu1_30.Text = 'Centered Normalized Gaussian';
            app.uimenu1_30.Tag = 'uimenu1_30';
            app.uimenu1_31 = uimenu(app.uimenu1_29);
            app.uimenu1_31.Text = 'Centered Non-normalized Gaussian';
            app.uimenu1_31.Tag = 'uimenu1_31';
            app.uimenu1_32 = uimenu(app.uimenu1_29);
            app.uimenu1_32.Text = 'Non-centered Normalized Gaussian';
            app.uimenu1_32.Tag = 'uimenu1_32';
            app.uimenu1_33 = uimenu(app.uimenu1_29);
            app.uimenu1_33.Text = 'Non-centered Non-normalized Gaussian';
            app.uimenu1_33.Tag = 'uimenu1_33';
            app.uimenu1_34 = uimenu(app.uimenu1_3);
            app.uimenu1_34.Text = 'Oscillations';
            app.uimenu1_34.Tag = 'uimenu1_34';
            app.uimenu1_35 = uimenu(app.uimenu1_34);
            app.uimenu1_35.Text = 'a*sin(b*x)';
            app.uimenu1_35.Tag = 'uimenu1_35';
            app.uimenu1_36 = uimenu(app.uimenu1_34);
            app.uimenu1_36.Text = 'a*cos(b*x)';
            app.uimenu1_36.Tag = 'uimenu1_36';
            app.uimenu1_37 = uimenu(app.uimenu1_34);
            app.uimenu1_37.Text = 'a*sin(b*x)+c';
            app.uimenu1_37.Tag = 'uimenu1_37';
            app.uimenu1_38 = uimenu(app.uimenu1_34);
            app.uimenu1_38.Text = 'a*cos(b*x)+c';
            app.uimenu1_38.Tag = 'uimenu1_38';
            app.uimenu1_39 = uimenu(app.uimenu1_34);
            app.uimenu1_39.Text = 'a*sin(b*x+phi)';
            app.uimenu1_39.Tag = 'uimenu1_39';
            app.uimenu1_40 = uimenu(app.uimenu1_34);
            app.uimenu1_40.Text = 'a*sin(b*x+phi)+c';
            app.uimenu1_40.Tag = 'uimenu1_40';
            app.uimenu1_41 = uimenu(app.uimenu1_3);
            app.uimenu1_41.Separator = 'on';
            app.uimenu1_41.Text = 'Nearest';
            app.uimenu1_41.Tag = 'uimenu1_41';
            app.uimenu1_42 = uimenu(app.uimenu1_3);
            app.uimenu1_42.Text = 'Spline';
            app.uimenu1_42.Tag = 'uimenu1_42';
            app.uimenu1_43 = uimenu(app.uimenu1_3);
            app.uimenu1_43.Text = 'Cubic';
            app.uimenu1_43.Tag = 'uimenu1_43';
            app.uimenu1_44 = uimenu(app.uimenu1_3);
            app.uimenu1_44.Separator = 'on';
            app.uimenu1_44.Text = '#1: myfit';
            app.uimenu1_44.Tag = 'uimenu1_44';
            app.uimenu1_45 = uimenu(app.uimenu1_3);
            app.uimenu1_45.Text = '#2: fit3';
            app.uimenu1_45.Tag = 'uimenu1_45';
            app.uimenu1_46 = uimenu(app.uimenu1_3);
            app.uimenu1_46.Text = '#3: test 3';
            app.uimenu1_46.Tag = 'uimenu1_46';
            app.uimenu1_47 = uimenu(app.uimenu1_3);
            app.uimenu1_47.Separator = 'on';
            app.uimenu1_47.Text = 'Other...';
            app.uimenu1_47.Tag = 'uimenu1_47';
            app.uimenu1_48 = uimenu(app.uimenu1);
            app.uimenu1_48.Text = 'Edit User Fit';
            app.uimenu1_48.Tag = 'uimenu1_48';
            app.uimenu1_49 = uimenu(app.uimenu1_48);
            app.uimenu1_49.Text = '#1: myfit';
            app.uimenu1_49.Tag = 'uimenu1_49';
            app.uimenu1_50 = uimenu(app.uimenu1_48);
            app.uimenu1_50.Text = '#2: fit3';
            app.uimenu1_50.Tag = 'uimenu1_50';
            app.uimenu1_51 = uimenu(app.uimenu1_48);
            app.uimenu1_51.Text = '#3: test 3';
            app.uimenu1_51.Tag = 'uimenu1_51';
            app.uimenu1_52 = uimenu(app.uimenu1_48);
            app.uimenu1_52.Separator = 'on';
            app.uimenu1_52.Text = 'New User Fit...';
            app.uimenu1_52.Tag = 'uimenu1_52';
            app.uimenu1_53 = uimenu(app.uimenu1_48);
            app.uimenu1_53.Separator = 'on';
            app.uimenu1_53.Text = 'Reset';
            app.uimenu1_53.Tag = 'uimenu1_53';
            app.uimenu1_54 = uimenu(app.uimenu1);
            app.uimenu1_54.Text = 'Refresh EzyFit menu';
            app.uimenu1_54.Tag = 'uimenu1_54';
            app.uimenu1_55 = uimenu(app.uimenu1);
            app.uimenu1_55.Text = 'Edit Fit Coefficients';
            app.uimenu1_55.Tag = 'uimenu1_55';
            app.uimenu1_56 = uimenu(app.uimenu1);
            app.uimenu1_56.Text = 'Show Fit Residuals';
            app.uimenu1_56.Tag = 'uimenu1_56';
            app.uimenu1_57 = uimenu(app.uimenu1);
            app.uimenu1_57.Separator = 'on';
            app.uimenu1_57.Accelerator = 'G';
            app.uimenu1_57.Text = 'Get Slope';
            app.uimenu1_57.Tag = 'uimenu1_57';
            app.uimenu1_58 = uimenu(app.uimenu1);
            app.uimenu1_58.Text = 'Show Slope...';
            app.uimenu1_58.Tag = 'uimenu1_58';
            app.uimenu1_59 = uimenu(app.uimenu1);
            app.uimenu1_59.Separator = 'on';
            app.uimenu1_59.Text = 'Plot Sample';
            app.uimenu1_59.Tag = 'uimenu1_59';
            app.uimenu1_60 = uimenu(app.uimenu1_59);
            app.uimenu1_60.Text = 'Linear';
            app.uimenu1_60.Tag = 'uimenu1_60';
            app.uimenu1_61 = uimenu(app.uimenu1_59);
            app.uimenu1_61.Text = 'Constant';
            app.uimenu1_61.Tag = 'uimenu1_61';
            app.uimenu1_62 = uimenu(app.uimenu1_59);
            app.uimenu1_62.Text = '3 polynomial curves';
            app.uimenu1_62.Tag = 'uimenu1_62';
            app.uimenu1_63 = uimenu(app.uimenu1_59);
            app.uimenu1_63.Text = 'Oscillations';
            app.uimenu1_63.Tag = 'uimenu1_63';
            app.uimenu1_64 = uimenu(app.uimenu1_59);
            app.uimenu1_64.Text = 'Damped oscillations';
            app.uimenu1_64.Tag = 'uimenu1_64';
            app.uimenu1_65 = uimenu(app.uimenu1_59);
            app.uimenu1_65.Text = 'Exponential decay';
            app.uimenu1_65.Tag = 'uimenu1_65';
            app.uimenu1_66 = uimenu(app.uimenu1_59);
            app.uimenu1_66.Text = 'Histogram with one peak';
            app.uimenu1_66.Tag = 'uimenu1_66';
            app.uimenu1_67 = uimenu(app.uimenu1_59);
            app.uimenu1_67.Text = 'Histogram with two peaks';
            app.uimenu1_67.Tag = 'uimenu1_67';
            app.uimenu1_68 = uimenu(app.uimenu1_59);
            app.uimenu1_68.Text = 'Power law';
            app.uimenu1_68.Tag = 'uimenu1_68';
            app.uimenu1_69 = uimenu(app.uimenu1_59);
            app.uimenu1_69.Text = 'Power law with a cutoff';
            app.uimenu1_69.Tag = 'uimenu1_69';
            app.uimenu1_70 = uimenu(app.uimenu1);
            app.uimenu1_70.Separator = 'on';
            app.uimenu1_70.Text = 'Axis';
            app.uimenu1_70.Tag = 'uimenu1_70';
            app.uimenu1_71 = uimenu(app.uimenu1_70);
            app.uimenu1_71.Text = 'Swap X-axis lin <-> log';
            app.uimenu1_71.Tag = 'uimenu1_71';
            app.uimenu1_72 = uimenu(app.uimenu1_70);
            app.uimenu1_72.Text = 'Swap Y-axis lin <-> log';
            app.uimenu1_72.Tag = 'uimenu1_72';
            app.uimenu1_73 = uimenu(app.uimenu1_70);
            app.uimenu1_73.Accelerator = 'B';
            app.uimenu1_73.Text = 'Swap both X- and Y- axis';
            app.uimenu1_73.Tag = 'uimenu1_73';
            app.uimenu1_74 = uimenu(app.uimenu1_70);
            app.uimenu1_74.Text = 'Include the origin';
            app.uimenu1_74.Tag = 'uimenu1_74';
            app.uimenu1_75 = uimenu(app.uimenu1_70);
            app.uimenu1_75.Text = 'Center the origin';
            app.uimenu1_75.Tag = 'uimenu1_75';
            app.uimenu1_76 = uimenu(app.uimenu1_70);
            app.uimenu1_76.Text = 'Enlarge to nearest power of 10';
            app.uimenu1_76.Tag = 'uimenu1_76';
            app.uimenu1_77 = uimenu(app.uimenu1);
            app.uimenu1_77.Separator = 'on';
            app.uimenu1_77.Text = 'Edit Default Settings...';
            app.uimenu1_77.Tag = 'uimenu1_77';
            app.uimenu1_78 = uimenu(app.uimenu1);
            app.uimenu1_78.Text = 'Help EzyFit';
            app.uimenu1_78.Tag = 'uimenu1_78';
            app.uimenu1_79 = uimenu(app.uimenu1);
            app.uimenu1_79.Text = 'Check for update';
            app.uimenu1_79.Tag = 'uimenu1_79';
            app.uimenu1_80 = uimenu(app.uimenu1);
            app.uimenu1_80.Text = 'EzyFit Home Page';
            app.uimenu1_80.Tag = 'uimenu1_80';
            app.uimenu1_81 = uimenu(app.uimenu1);
            app.uimenu1_81.Text = 'About EzyFit';
            app.uimenu1_81.Tag = 'uimenu1_81';
            app.uitoolbar1 = uitoolbar(app.figure1);
            app.uitoolbar1.Tag = 'uitoolbar1';
            app.uitoggletool1 = uitoggletool(app.uitoolbar1);
            app.uitoggletool1.Tag = 'uitoggletool1';
            app.uitoggletool1.Tooltip = 'Pan';
            app.uitoggletool1.ClickedCallback = createCallbackFcn(app, @uitoggletool1_ClickedCallback, true);
            app.uitoggletool1.Icon = 'uitoggletool1_image.png';
            app.uitoggletool2 = uitoggletool(app.uitoolbar1);
            app.uitoggletool2.Tag = 'uitoggletool2';
            app.uitoggletool2.Tooltip = 'Zoom In';
            app.uitoggletool2.ClickedCallback = createCallbackFcn(app, @uitoggletool2_ClickedCallback, true);
            app.uitoggletool2.Icon = 'uitoggletool2_image.png';
            app.uitoggletool3 = uitoggletool(app.uitoolbar1);
            app.uitoggletool3.Tag = 'uitoggletool3';
            app.uitoggletool3.Tooltip = 'Data Cursor';
            app.uitoggletool3.ClickedCallback = createCallbackFcn(app, @uitoggletool3_ClickedCallback, true);
            app.uitoggletool3.Icon = 'uitoggletool3_image.png';
            app.dataPlot = uiaxes(app.figure1);
            app.dataPlot.Tag = 'dataPlot';
            app.allDataPlot = uiaxes(app.figure1);
            app.allDataPlot.Tag = 'allDataPlot';
            app.triggerPlot = uiaxes(app.figure1);
            app.triggerPlot.Tag = 'triggerPlot';
            app.startPanel = uipanel(app.figure1);
            app.startPanel.Title = 'Sampling';
            app.startPanel.Tag = 'startPanel';
            app.startDAQ = uibutton(app.startPanel, 'push');
            app.startDAQ.ButtonPushedFcn = createCallbackFcn(app, @startDAQ_Callback, true);
            app.startDAQ.Tag = 'startDAQ';
            app.startDAQ.Text = 'Start-DAQ';
            app.freq = uieditfield(app.startPanel, 'text');
            app.freq.Tag = 'freq';
            app.freq.Value = '40000';
            app.freqText = uilabel(app.startPanel);
            app.freqText.Tag = 'freqText';
            app.freqText.Text = 'f [Hz]';
            app.TimeoutText = uilabel(app.startPanel);
            app.TimeoutText.Tag = 'TimeoutText';
            app.TimeoutText.Text = 'Timeout';
            app.sec = uieditfield(app.startPanel, 'text');
            app.sec.Tag = 'sec';
            app.sec.Value = '1200';
            app.reset = uibutton(app.startPanel, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @reset_Callback, true);
            app.reset.Tag = 'reset';
            app.reset.Text = 'Stop-DAQ';
            app.text9 = uilabel(app.startPanel);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Delay';
            app.delSam = uieditfield(app.startPanel, 'text');
            app.delSam.Tag = 'delSam';
            app.delSam.Value = '25';
            app.text10 = uilabel(app.startPanel);
            app.text10.Tag = 'text10';
            app.text10.Text = '[Hz]';
            app.text11 = uilabel(app.startPanel);
            app.text11.Tag = 'text11';
            app.text11.Text = '[s]';
            app.text12 = uilabel(app.startPanel);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Samples';
            app.Device = uidropdown(app.startPanel);
            app.Device.Items = {'Dev1', 'Dev2', 'Dev3'};
            app.Device.Tag = 'Device';
            app.Device.Value = 'Dev2';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Nifty Button Panel';
            app.uipanel6.Tag = 'uipanel6';
            app.home = uibutton(app.uipanel6, 'push');
            app.home.ButtonPushedFcn = createCallbackFcn(app, @home_Callback, true);
            app.home.Tag = 'home';
            app.home.Text = 'Clear c.win';
            app.Save = uipanel(app.figure1);
            app.Save.Title = 'Save Options';
            app.Save.Tag = 'Save';
            app.saveDir = uilabel(app.Save);
            app.saveDir.Tag = 'saveDir';
            app.saveDir.Text = 'E:\Yuri';
            app.openFolder = uibutton(app.Save, 'push');
            app.openFolder.ButtonPushedFcn = createCallbackFcn(app, @openFolder_Callback, true);
            app.openFolder.Tag = 'openFolder';
            app.openFolder.Text = 'Open folder';
            app.name = uieditfield(app.Save, 'text');
            app.name.Tag = 'name';
            app.name.Value = 'TagnameOfFile';
            app.AnalogInput = uibuttongroup(app.figure1);
            app.AnalogInput.Title = 'Analog input';
            app.AnalogInput.Tag = 'AnalogInput';
            app.a1 = uicheckbox(app.AnalogInput);
            app.a1.Tag = 'a1';
            app.a1.Text = 'a1';
            app.a2 = uicheckbox(app.AnalogInput);
            app.a2.Tag = 'a2';
            app.a2.Text = 'a2';
            app.a3 = uicheckbox(app.AnalogInput);
            app.a3.Tag = 'a3';
            app.a3.Text = 'a3';
            app.a4 = uicheckbox(app.AnalogInput);
            app.a4.Tag = 'a4';
            app.a4.Text = 'a4';
            app.a5 = uicheckbox(app.AnalogInput);
            app.a5.Tag = 'a5';
            app.a5.Text = 'a5';
            app.a6 = uicheckbox(app.AnalogInput);
            app.a6.Tag = 'a6';
            app.a6.Text = 'a6';
            app.a7 = uicheckbox(app.AnalogInput);
            app.a7.Tag = 'a7';
            app.a7.Text = 'a7';
            app.a7.Value = true;
            app.a0 = uicheckbox(app.AnalogInput);
            app.a0.Tag = 'a0';
            app.a0.Visible = 'off';
            app.a0.Text = 'a0';
            app.a0.Value = true;
            app.TriggerPanel = uipanel(app.figure1);
            app.TriggerPanel.Title = 'Trigger setings';
            app.TriggerPanel.Tag = 'TriggerPanel';
            app.stopValue = uilabel(app.TriggerPanel);
            app.stopValue.Tag = 'stopValue';
            app.stopValue.Text = 'Stop when: ';
            app.stopTrig = uieditfield(app.TriggerPanel, 'text');
            app.stopTrig.Tag = 'stopTrig';
            app.stopTrig.Value = '3.5';
            app.triggerValue = uilabel(app.TriggerPanel);
            app.triggerValue.Tag = 'triggerValue';
            app.triggerValue.Text = 'Start when trig >';
            app.triggVal = uieditfield(app.TriggerPanel, 'text');
            app.triggVal.Tag = 'triggVal';
            app.triggVal.Value = '3';
            app.text13 = uilabel(app.TriggerPanel);
            app.text13.Tag = 'text13';
            app.text13.Text = '[V]';
            app.text14 = uilabel(app.TriggerPanel);
            app.text14.Tag = 'text14';
            app.text14.Text = '[V]';
            app.trigOrNot = uidropdown(app.TriggerPanel);
            app.trigOrNot.Items = {'With trigger', 'no trigger'};
            app.trigOrNot.ValueChangedFcn = createCallbackFcn(app, @trigOrNot_Callback, true);
            app.trigOrNot.Tag = 'trigOrNot';
            app.trigOrNot.Value = 'With trigger';
            app.exit = uibutton(app.figure1, 'push');
            app.exit.ButtonPushedFcn = createCallbackFcn(app, @exit_Callback, true);
            app.exit.Tag = 'exit';
            app.exit.Text = 'Exit SampSamp';
            app.listbox = uilistbox(app.figure1);
            app.listbox.Items = {'Listbox'};
            app.listbox.ValueChangedFcn = createCallbackFcn(app, @listbox_Callback, true);
            app.listbox.Tag = 'listbox';
            app.listbox.Value = 'Listbox';
            app.openFile = uibutton(app.figure1, 'push');
            app.openFile.ButtonPushedFcn = createCallbackFcn(app, @openFile_Callback, true);
            app.openFile.Tag = 'openFile';
            app.openFile.Text = 'Open file';
            app.positionSampSamp = uibutton(app.figure1, 'push');
            app.positionSampSamp.ButtonPushedFcn = createCallbackFcn(app, @positionSampSamp_Callback, true);
            app.positionSampSamp.Tag = 'positionSampSamp';
            app.positionSampSamp.Text = 'Position SampSamp';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)sampsamp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end